classdef rowingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ReconnectButton          matlab.ui.control.Button
        MeterSecGaugeLabel       matlab.ui.control.Label
        MeterSecGauge            matlab.ui.control.LinearGauge
        UIAxes                   matlab.ui.control.UIAxes
        StrokesMinuteGaugeLabel  matlab.ui.control.Label
        StrokesMinuteGauge       matlab.ui.control.LinearGauge
        DistanceLabel            matlab.ui.control.Label
        TimeLabel                matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        PollButton               matlab.ui.control.Button
        Label_4                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
    end


    properties (Access = public)
        rower % S4 rowing computer interface
        connected
        message
        value
        pulseCount
        polltimer
        messages ={'055' '1E1'};
        nextmessage = 1;
    end

    methods (Access = private)
    
        function connect(app)
            if app.rower.connect
                app.ReconnectButton.Visible = 'on';
                app.ReconnectButton.Visible = 'off';
                app.ReconnectButton.Enable = 'off';
            else
                app.ReconnectButton.Visible = 'on';
                app.ReconnectButton.Enable = 'on';
                msgbox('Please Connect S4 Rower then Reconnect')
            end
            
        end
        
    end

    methods (Access = public)
    
        function setDistance(app,distance)
            app.Label_2.Text = distance;
            
        end
        
        function SetStrokesPerMin(app,strokes)
            app.StrokesMinuteGauge.Value= strokes;
            app.StrokesMinuteGaugeLabel.Text= strcat('Strokes/Minute: ',string(strokes));            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rower = WaterRowerS4('COM5',app);
            app.connect();   
            app.connected = 2;
            app.polltimer  = timer;
            app.polltimer.ExecutionMode = 'fixedSpacing';
            app.polltimer.Period = 0.25;
            app.polltimer.Tag = 'WaterRower';
            app.polltimer.TimerFcn = {@pollSignals,app};
            
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            app.connect();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             app.rower.shutdown;
            delete( instrfind('Tag','WaterRower'));
            delete(timerfind('Tag','WaterRower'));
            delete(app)
            
        end

        % Button pushed function: PollButton
        function PollButtonPushed(app, event)
            start(app.polltimer);
            %fprintf(app.rower.USB,'IRD1E1');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ReconnectButton = uibutton(app.UIFigure, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.MeterSecGaugeLabel = uilabel(app.UIFigure);
            app.MeterSecGaugeLabel.Text = 'Meter/Sec';
            app.MeterSecGauge = uigauge(app.UIFigure, 'linear');
            app.MeterSecGauge.Limits = [0 6];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StrokesMinuteGaugeLabel = uilabel(app.UIFigure);
            app.StrokesMinuteGaugeLabel.Text = 'Strokes/Minute';
            app.StrokesMinuteGauge = uigauge(app.UIFigure, 'linear');
            app.StrokesMinuteGauge.Limits = [0 50];
            app.DistanceLabel = uilabel(app.UIFigure);
            app.DistanceLabel.Text = 'Distance';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '000';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '000000';
            app.PollButton = uibutton(app.UIFigure, 'push');
            app.PollButton.ButtonPushedFcn = createCallbackFcn(app, @PollButtonPushed, true);
            app.PollButton.Text = 'Poll';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = ':';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '00';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rowingGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end